classdef UI < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GazeVectorParametersLabel  matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        VisualizationButton        matlab.ui.control.Button
        DataButton                 matlab.ui.control.Button
    end

    properties (Access = public)
        dir_x double
        dir_y double
        dir_z double
        ray_fx double % finalPosition.x
        ray_fy double % finalPosition.y
        ray_fz double % finalPosition.z
        ray_ox double % rayOrigin.x
        ray_oy double % rayOrigin.y
        ray_oz double % rayOrigin.z
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DataButton
        function data(comp, event)
            filename = 'dvs_data.csv';
            M = readtable(filename, VariableNamingRule="preserve");
            comp.UITable.Data = M;
            comp.UITable.ColumnName = M.Properties.VariableNames;
            %mat = readmatrix(filename);
            comp.ray_ox = M{:,1};
            comp.ray_oy = M{:,2};
            comp.ray_oz = M{:,3};
            comp.dir_x = M{:,4};
            comp.dir_y = M{:,5};
            comp.dir_z = M{:,6};
            comp.ray_fx = M{:,7};
            comp.ray_fy = M{:,8};
            comp.ray_fz = M{:,9};
        end

        % Button pushed function: VisualizationButton
        function visualize(comp, event)
            filename = 'dvs_with_visualizer.mp4';
            implay(filename);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 939 630];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.DataButton = uibutton(comp, 'push');
            comp.DataButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @data, true);
            comp.DataButton.Text = 'Data';
            comp.VisualizationButton = uibutton(comp, 'push');
            comp.VisualizationButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @visualize, true);
            comp.VisualizationButton.Text = 'Visualization';
            comp.UITable = uitable(comp);
            comp.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            comp.UITable.RowName = {};
            comp.GazeVectorParametersLabel = uilabel(comp);
            comp.GazeVectorParametersLabel.Text = 'Gaze Vector Parameters';
        end
    end
end